classdef defractal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        FractalExponentLabel            matlab.ui.control.Label
        FractalExponentEditField        matlab.ui.control.NumericEditField
        SelectpointsButton              matlab.ui.control.Button
        ValuesLabel                     matlab.ui.control.Label
        SurfacetemperatureCEditFieldLabel  matlab.ui.control.Label
        SurfacetemperatureCEditField    matlab.ui.control.NumericEditField
        CurietemperatureCEditFieldLabel  matlab.ui.control.Label
        CurietemperatureCEditField      matlab.ui.control.NumericEditField
        SurfaceheatproductionmicroWm3EditFieldLabel  matlab.ui.control.Label
        SurfaceheatproductionmicroWm3EditField  matlab.ui.control.NumericEditField
        EmpiricalheatproductiondepthdistributionparameterkmEditField  matlab.ui.control.NumericEditField
        ThermalconductivityWmKEditFieldLabel  matlab.ui.control.Label
        ThermalconductivityWmKEditField  matlab.ui.control.NumericEditField
        PlotGeothermButton              matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        FlightaltitudekmEditFieldLabel  matlab.ui.control.Label
        FlightaltitudekmEditField       matlab.ui.control.NumericEditField
        EmpiricalheatproductiondepthdistributionparameterkmLabel  matlab.ui.control.Label
        DefractalMethodLabel            matlab.ui.control.Label
        ResolutionkmEditFieldLabel      matlab.ui.control.Label
        ResolutionkmEditField           matlab.ui.control.NumericEditField
    end


    methods (Access = public)
    
        function [w,pw,ps,ci] = fractal_correction(~,x,b1)
            w=x(:,1);
            ps=(x(:,2)- log( 2*pi*w )) + log((( 2*pi*w ).^( 0.5*(b1) ) ));% for Z0
            pw=x(:,2)+log(( 2*pi*w ).^(0.5*(b1)));% for Zt
            ci=x(:,3);
            
        end
        
        function b = data_aero(~,fileToRead1)
            %IMPORTFILE(FILETOREAD1)
            %  Imports data from the specified file
            %  FILETOREAD1:  file to read
            %  Auto-generated by MATLAB on 23-May-2019 17:59:12
            % Import the file

            rawData1 = importdata(fileToRead1);
            % For some simple files (such as a CSV or JPEG files), IMPORTDATA might
            % return a simple array.  If so, generate a structure so that the output
            % matches that from the Import Wizard.
            [~,name] = fileparts(fileToRead1);
            newData1.(matlab.lang.makeValidName(name)) = rawData1;
            % Create new variables in the base workspace from those fields.
            vars = fieldnames(newData1);
            for i = 1:length(vars)
                assignin('base', vars{i}, newData1.(vars{i}));
            end
            b = rawData1;
            
        end
        
        function Z_f = detrend_2d(~,Z)
            % This function is written by Munther Gdeisat-The General Engineering
            % Research Institute (GERI) at Liverpool John Moores University.
            % This program is written on 9th October 2011
            % This function is the 2D equivalent of detrend function in Matlab
            % Z_f = DETREND(Z) removes the best plane fit trend from the data
            % in the 2D array Z and returns the residual in the 2D array Z_f
            if size(Z,2) < 2
                disp('Z must be a 2D array')
                return
            end 
            M = size(Z,2);
            N = size(Z,1);
            [X,Y] = meshgrid(1:M,1:N);
            %Make the 2D data as 1D vector
            Xcolv = X(:); % Make X a column vector
            Ycolv = Y(:); % Make Y a column vector
            Zcolv = Z(:); % Make Z a column vector
            Const = ones(size(Xcolv)); % Vector of ones for constant term
            % find the coeffcients of the best plane fit
            Coefficients = [Xcolv Ycolv Const]\Zcolv; % Find the coefficients
            XCoeff = Coefficients(1); % X coefficient
            YCoeff = Coefficients(2); % X coefficient
            CCoeff = Coefficients(3); % constant term
            % detrend the data
            Z_p = XCoeff * X + YCoeff * Y + CCoeff;
            Z_f = Z - Z_p;
            
        end
        
        function cv3 = raas(~,anom,dl)
            % function raps(img)
            % Computes and plots radially a power spectrum of magnetic data in
            % a GEOtif image of the amplitde spectrum
            % Based and modified on (C) E. Ruzanski, RCG, 2009 and 
            % Tom Ridsdill-Smith  March 2000
            %% Process image size information
            [ny, nx] = size(anom);
            N = max(nx,ny);
            if mod(N,2) ~= 0
                N = N + 1;
            end
            TFA = fftshift(fft2(anom,N,N));
            EA = 2*abs(TFA)/(ny*nx); % Espectro de amplitud 2D
            % Compute radially average amplitude spectrum
            n = -N/2:N/2-1;
            [NX, NY] = meshgrid(n,n);
            [~, rho] = cart2pol(NX, NY);           % Convert to polar coordinate axes
            rho = round(rho);
            i = cell(N/2+1, 1);
            Pf = zeros(N/2+1,1);
            CF = zeros(N/2+1,1);
            nm = zeros(N/2+1,1);
            for r = 0:N/2
                i{r + 1} = find(rho == r);
                Pf(r + 1) = nanmean(log(EA(i{r+1}))); %Amplitude spectra
                CF(r + 1) = 1.96*(nanstd(log(EA(i{r+1}))))...
                     /sqrt(length(EA(i{r+1}))); %confidence interval
            end
            % Calculate the 1D wavenumber for a signal of even length
            kn = 1/(2*dl); % Nyquist wavenumber
            k0 = 1/(N*dl); % Fundamental wavenumber
            w = (0:k0:kn)';
            cv2=[w(2:end),Pf(2:end),CF(2:end),nm(2:end)];
            cv3 =cv2(cv2(:,3) <= 1, :);
        end
        
        function results = func18(~)
            [pointlist,xs,ys] = selectdata(Input);
            results = [pointlist,xs,ys];
        end
        function [x, y] = ind(~,xi,yi,dx)

            n = length(dx);
            x = ones(n,1);
            y = ones(n,1);
            for i = 1:n
                c = find(xi ==dx(i));
                x(i) = xi(c);
                y(i) = yi(c);
            end
        end
        function x = fileid(~,data)
        % This program identifies the input file extension. If the files
        % have a *grd extention one the return value is 0. If the file has
        % a *.tif extension, the return value is 1.
        [~,~,ext] = fileparts(data);
        if (strcmpi(strtrim(ext),'.grd'))
            x=0;
        elseif (strcmpi(strtrim(ext),'.tif'))
            x=1;
        else
            disp('Error: Unexpected file')
            return
        end
        end
        function [D,varargout] = getgrd2(~,filename)
            % Read a Geosoft 2-byte binary grid
            % Syntax: [D,varargout] = getgrd2(filename);
            % Import: filename = string of grid filename, eg. 'mag.grd'
            %                    Use full path if grid is not in current directory
            %                    eg. 'd:\data\mag.grd'
            % Export: D = 2D array of real data
            %         varargout = Xaxis,Yaxis,dummy
            %         Xaxis,Yaxis = x- and y-axis coordinates
            %         dummy = dummy value as a real in D
            % Usage : D = getgrd2(filename); % Array only
            %         [D,X,Y] = getgrd2(filename); % Data and coordinates
            %         To locate dummy pixels and image use:
            %         [D,X,Y,dummy] = getgrd2(filename);
            %         dummypos = find(D<=dummy);
            %         D(dummypos) = NaN;
            %         imagesc(D,'XData',[min(X),max(X)],'YData',[min(Y),max(Y)]);
            %         set(gca,'YDir','normal');
            % Notes : Requires files grdheadset.m and grdelemset.m
            %         Geosoft short (2-byte, signed integer) grids ONLY
            % Author: Adam O'Neill, 31/1/02
            
            % Set some constants
            %griddummy = -32767;
            grdhead = struct(...
               'es',0,...
               'sf',4,...
               'ne',8,...
               'nv',12,...
               'kx',16,...
               'de',20,...
               'dv',28,...
               'x0',36,...
               'y0',44,...
               'rot',52,...
               'zbase',60,...
               'zmult',68,...
               'label',76,...
               'mapno',124,...
               'proj',140,...
               'unitx',144,...
               'unity',148,...
               'unitz',152,...
               'nvpts',156,...
               'izmin',160,...
               'izmax',164,...
               'izmed',168,...
               'izmea',172,...
               'zvar',176,...
               'prcs',184,...
               'totallength',512); % Geosoft 2 byte grids ONLY
            grdelem = struct(...
               'es','int32',... % Data storage
               'sf','int32',...
               'ne','int32',...
               'nv','int32',...
               'kx','int32',...
               'de','float64',... % Geographic information
               'dv','float64',...
               'x0','float64',...
               'y0','float64',...
               'rot','float64',...
               'zbase','float64',... % Data (Z) scaling
               'zmult','float64',...
               'label','char*48',...
               'mapno','char*16',...
               'proj','int32',... % Optional parameters
               'unitx','int32',...
               'unity','int32',...
               'unitz','int32',...
               'nvpts','int32',...
               'izmin','int32',...
               'izmax','int32',...
               'izmed','int32',...
               'izmea','int32',...
               'zvar','float64',...
               'prcs','int32',...
               'datasize','int16'); % Geosoft 2 byte grids ONLY;

            % Open data file and get necessary header info
            fid = fopen(filename,'r','n');
            
            % Data storage
            % fseek(fid,grdhead.es,'bof');
            % es = fread(fid,1,grdelem.es);
            
            % fseek(fid,grdhead.sf,'bof');
            % sf = fread(fid,1,grdelem.sf);
            
            fseek(fid,grdhead.ne,'bof');
            ne = fread(fid,1,grdelem.ne); % No. of elements per vector
            
            fseek(fid,grdhead.nv,'bof');
            nv = fread(fid,1,grdelem.nv); % No. of vectors
            
            fseek(fid,grdhead.kx,'bof');
            kx = fread(fid,1,grdelem.kx); % Orientation sense
            % kx = 1, vectors run left-right
            % kx = -1, vectors run up-down
            
            % Geographic information
            fseek(fid,grdhead.de,'bof');
            de = fread(fid,1,grdelem.de);
            
            fseek(fid,grdhead.dv,'bof');
            dv = fread(fid,1,grdelem.dv);
            
            fseek(fid,grdhead.x0,'bof');
            x0 = fread(fid,1,grdelem.x0);
            
            fseek(fid,grdhead.y0,'bof');
            y0 = fread(fid,1,grdelem.y0);
            
            % fseek(fid,grdhead.rot,'bof');
            % rot = fread(fid,1,grdelem.rot);
            
            % Data (Z) scaling
            fseek(fid,grdhead.zbase,'bof');
            zbase = fread(fid,1,grdelem.zbase);
            
            fseek(fid,grdhead.zmult,'bof');
            zmult = fread(fid,1,grdelem.zmult);
            
            % Go to start of data stream and read into array
            fseek(fid,grdhead.totallength,'bof');
            TEMP = fread(fid,[ne nv],grdelem.datasize); 
            
            % Make the output array
            if eq(kx,1)
               TEMP2 = TEMP';
            elseif eq(kx,-1)
               TEMP2 = TEMP;
            else
                disp('kx not valid')
               return
            end   
            
            % Convert to reals
            D = (TEMP2./zmult) + zbase;
            clear TEMP TEMP2
            
            % Optional outputs
            switch nargout
            case {1,2}
            case {3,4}
               Xaxis = (x0 : de : x0+de*(ne-1));
               Yaxis = (y0 : dv : y0+dv*(nv-1));
               dummy = min(min(D));
               varargout{1} = Xaxis;
               varargout{2} = Yaxis;
            otherwise
               error('Require 1 or 4 outputs') 
            end   
            
            if nargout>3
                varargout{3} = dummy;
            end
            fclose(fid);
        end
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FractalExponentEditField
        function Fractal(app, event)
            xsi = getappdata(app.UIFigure,'xs');
            xs2i = getappdata(app.UIFigure,'xs2');
            a2 = getappdata(app.UIFigure,'data');
            Zc = app.FlightaltitudekmEditField.Value;
            b2 = app.FractalExponentEditField.Value;
            [w2,pw2,ps2,ci1] = fractal_correction(app,a2,b2);
            n = length(xsi);
            if n == 0
                % Plot 1
                plot(app.UIAxes,w2,pw2,'k.-');
                ylabel(app.UIAxes,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
                xlabel(app.UIAxes,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
                app.UIAxes.XLim = [0 w2(end)];
                app.UIAxes.YLimMode = 'auto';
                
                % Plot 2
                plot(app.UIAxes2,w2,ps2,'k.-');
                ylabel(app.UIAxes2,'$\ln(\frac{A(k)}{2\pi k}$)','Interpreter','latex','FontSize',12)
                xlabel(app.UIAxes2,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
                app.UIAxes2.XLim = [0 w2(end)];
                app.UIAxes2.YLimMode = 'auto';
            else
                %fm1 = getappdata(app.UIFigure,'fm1');
                
                % Calculations
                [xs2, ys2] = ind(app,w2,pw2,xs2i);
                p2=polyfit(xs2,ys2,1); %slope Zt and intercept
                wh2=min(xs2)-0.001:0.001:max(xs2)+0.001; %wavenumber to graphs
                kk2=polyval(p2,wh2); %values for files
                Zt=(-1)*(p2(1)/(2*pi));
                sd2=sqrt(sum((ys2-polyval(p2,xs2)).^2)/(length(xs2))); %standart deviation
                Zt_error= sd2/((2*pi)*(max(xs2)-min(xs2)));
                
                % Plot 1
                plot(app.UIAxes,w2,pw2,'k.-');
                ylabel(app.UIAxes,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
                xlabel(app.UIAxes,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
                hold(app.UIAxes,'on')
                plot(app.UIAxes,wh2,kk2,'-r','Linewidth',1,'Markersize',12)
                hold(app.UIAxes,'off')
                app.UIAxes.XLim = [0 0.2];
                
                % Calculations 
                [xs, ys] = ind(app,w2,ps2,xsi);
                pl = polyfit(xs,ys,1); %slope Z0 and intercept
                w1 = min(xs)-0.002:0.001:max(xs)+0.002; %wavenumber to graphs
                kk = polyval(pl,w1); %values for files
                Z0 = (-1)*(pl(1)/(2*pi));
                sd1 = sqrt(sum((ys-polyval(pl,xs)).^2)/(length(xs))); %standart deviation
                p1_err = sd1/((2*pi)*(max(xs)-min(xs)));
                Zb = ((2*Z0)-Zt); %depth to the bottom of the magnetic source (DBMS)
                Zb_err = sqrt((4*(p1_err^2))+(Zt_error^2)); %DBMS uncertainity (Martos et al., 2019)
                
                % Plot 2
                plot(app.UIAxes2,w2,ps2,'k.-');
                ylabel(app.UIAxes2,'$\ln(\frac{A(k)}{2\pi k}$)','Interpreter','latex','FontSize',12)
                xlabel(app.UIAxes2,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2,w1,kk,'-r','Linewidth',1,'Markersize',12)
                hold(app.UIAxes2,'off')
                app.UIAxes2.XLim = [0 0.2];
                app.UIAxes2.YLim = [floor(min(ps2(w2<0.2))) ceil(max(ps2) + 1)];
                
                %% Forward model
                c1 = find(pw2==ys2(end));
                sx=w2(1:c1);
                sy=pw2(1:c1); %Amplitude spectrum
                d = exp(sy);
                G = exp(-2*pi*sx*Zt).*(1-exp(-2*pi*sx*(Zb-Zt)));
                x0 = log((G'*G)\G'*d);
                func = @(x0,x)x0 - ((2*pi*x)*Zt) + log((1-exp(-2*pi*x*(Zb-Zt))));
                [xc,resnorm] = lsqcurvefit(func,x0,sx,sy);
                mf=sqrt(resnorm/length(sx));
                wn=linspace(w2(1),w2(end));
                fm1=func(xc,wn); %Synthetic Spectrum
                fit_fm=mf; %Misfit
                
                % Plot 3
                    % Confidence intervals
                p4 = plot(app.UIAxes3,w2,pw2 + ci1,'k');
                hold(app.UIAxes3,'on')
                plot(app.UIAxes3,w2,pw2 - ci1,'k')
                plot(app.UIAxes3,w2,a2(:,2) + ci1,'k')
                plot(app.UIAxes3,w2,a2(:,2) - ci1,'k')
                    %Plots
                p2 = plot(app.UIAxes3,w2,pw2,'b-','Linewidth',1,'Markersize',12);
                p1 = plot(app.UIAxes3,w2,a2(:,2),'g-','Linewidth',1,'Markersize',12);
                p3 = plot(app.UIAxes3,wn,fm1,'-r','Linewidth',1.5,'Markersize',12);
                ylabel(app.UIAxes3,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
                xlabel(app.UIAxes3,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
                hold(app.UIAxes3,'off')
                app.UIAxes3.XLim = [0 0.2];
                legend(app.UIAxes3,[p1 p2 p3 p4],{'Observed spectrum','Corrected spectrum',...
                    'Synthetic spectrum','95% confidence interval'})
                
                app.ValuesLabel.Text = sprintf('Values\nFractal exponent α: %0.2f\nZt = %0.2f km \x00B1 %0.2f km\nZ0 = %0.2f km \x00B1 %0.2f km\nZb = %0.2f km \x00B1 %0.2f km\nMisfit = %0.4f',...
                    b2,Zt-Zc,Zt_error,Z0-Zc,p1_err,Zb-Zc,Zb_err,fit_fm);
                
                
                setappdata(app.UIFigure,'b2',b2)
                setappdata(app.UIFigure,'Zt',Zt)
                setappdata(app.UIFigure,'Zt_error',Zt_error)
                setappdata(app.UIFigure,'Z0',Z0)
                setappdata(app.UIFigure,'p1_err',p1_err)
                setappdata(app.UIFigure,'Zb',Zb)
                setappdata(app.UIFigure,'Zb_err',Zb_err)
                setappdata(app.UIFigure,'fit_fm',fit_fm)
                setappdata(app.UIFigure,'xs',xs)
                setappdata(app.UIFigure,'xs2',xs2)                
                
            end
            
            setappdata(app.UIFigure,'b2',b2)
            setappdata(app.UIFigure,'w2',w2)
            setappdata(app.UIFigure,'pw2',pw2)
            setappdata(app.UIFigure,'ps2',ps2)
            setappdata(app.UIFigure,'ci1',ci1)
            setappdata(app.UIFigure,'Zc',Zc)
        end

        % Callback function: CurietemperatureCEditField, 
        % EmpiricalheatproductiondepthdistributionparameterkmEditField, 
        % PlotGeothermButton, 
        % SurfaceheatproductionmicroWm3EditField, 
        % SurfacetemperatureCEditField, 
        % ThermalconductivityWmKEditField
        function Geotherm(app, event)
            Zc = app.FlightaltitudekmEditField.Value;
            Ts = app.SurfacetemperatureCEditField.Value;
            Tc = app.CurietemperatureCEditField.Value;
            A = app.SurfaceheatproductionmicroWm3EditField.Value*1e-6;
            b = app.EmpiricalheatproductiondepthdistributionparameterkmEditField.Value*1e3;
            k = app.ThermalconductivityWmKEditField.Value;
            Zb = 1000*(getappdata(app.UIFigure,'Zb')-Zc);
            
            qs = k*(Tc-Ts)/Zb + A*b - ((A*b^2)/Zb)*(1-exp(-Zb/b)); %Martos et al., 2017 %heat flow at the surface (W/m2)
            zb=Zb/1000;
            if zb < 20
                dlim=ceil(zb/2)*2;
            else
                dlim=ceil(zb/5)*5;
            end
            z=0:1000*dlim;
            Tcrust=Ts + z*qs/k + A*b*(b-z)/k - A*b^2*exp(-z/b)/k; %Ravat et al., 2016
            % tm=max(Tcrust); %Tlim=ceil(tm/100)*100;
            
            % Geotherm
            plot(app.UIAxes4,Tcrust,z'/1000,'Linewidth',1.5)
            hold(app.UIAxes4,'on')
            plot(app.UIAxes4,[0, max(Tcrust)], [zb, zb],'--k','LineWidth',1.5) %Curie Point Depth
            plot(app.UIAxes4,[Tc, Tc], [0, dlim],'--r','LineWidth',1.5) %Curie temperature
            axis(app.UIAxes4,'ij')
            app.UIAxes4.XLim = [0,max(Tcrust)];
            app.UIAxes4.YLim = [0,dlim];
            legend(app.UIAxes4,{'Geotherm',[' CPD = ' num2str(zb,'%.2f') ' km'],[' CT = ' num2str(Tc) ' °C']})
            hold(app.UIAxes4,'off')
            
            
            b2 = app.FractalExponentEditField.Value;
            Zt = getappdata(app.UIFigure,'Zt');
            Zt_error = getappdata(app.UIFigure,'Zt_error');
            Z0 = getappdata(app.UIFigure,'Z0');
            p1_err = getappdata(app.UIFigure,'p1_err');
            Zb = getappdata(app.UIFigure,'Zb');
            Zb_err = getappdata(app.UIFigure,'Zb_err');
            fit_fm = getappdata(app.UIFigure,'fit_fm');
            
            app.ValuesLabel.Text = sprintf('Values\nFractal exponent α: %0.2f\nZt = %0.2f km \x00B1 %0.2f km\nZ0 = %0.2f km \x00B1 %0.2f km\nZb = %0.2f km \x00B1 %0.2f km\nMisfit = %0.4f\nqs = %.4f mW/m2\n',...
                b2,Zt-Zc,Zt_error,Z0-Zc,p1_err,Zb-Zc,Zb_err,fit_fm,qs*1e3);
        end

        % Button pushed function: SelectFileButton
        function Readdata(app, event)
            Restart(app,event)
            [file, path] = uigetfile({'*.grd';'*.tif'});
            app.Label.Text = sprintf(file);
            if fileid(app,file) == 1
                b = data_aero(app,fullfile(path, file));
            elseif fileid(app,file) == 0
                b = getgrd2(app,fullfile(path, file));
            end
            setappdata(app.UIFigure,'b',b)
            set(app.ResolutionkmEditField, 'Enable', 'on');
            set(app.ResolutionkmEditFieldLabel, 'Enable', 'on');
            dl = app.ResolutionkmEditField.Value;
            b = getappdata(app.UIFigure,'b');
            dt = detrend_2d(app,b); % Polynomial Trend Surface Estimation (degree = 1)
            a2 = raas(app,dt,dl); % Radially average power spectrum
            plot(app.UIAxes,a2(:,1),a2(:,2),'k.-');
            ylabel(app.UIAxes,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            app.UIAxes.XLim = [0 a2(end,1)];
            app.UIAxes.YLimMode = 'auto';
                
            plot(app.UIAxes2,a2(:,1),a2(:,2)-log(2*pi*a2(:,1)),'k.-');
            ylabel(app.UIAxes2,'$\ln(\frac{A(k)}{2\pi k}$)','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes2,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            app.UIAxes2.XLim = [0 a2(end,1)];
            app.UIAxes2.YLimMode = 'auto';
            
            setappdata(app.UIFigure,'data',a2)
            xs = []; % Data not selected yet
            xs2 = []; % Data not selected yet
            setappdata(app.UIFigure,'xs',xs)
            setappdata(app.UIFigure,'xs2',xs2)
            % Initial value
            [w2,pw2,ps2,ci1] = fractal_correction(app,a2,0);
            
            set(app.FractalExponentEditField, 'Enable', 'on'); set(app.FractalExponentLabel, 'Enable', 'on')
            set(app.FlightaltitudekmEditField, 'Enable', 'on'); set(app.FlightaltitudekmEditFieldLabel, 'Enable', 'on')
            set(app.SelectpointsButton,'Enable','on')
            set(app.RestartButton,'Enable','on')
            
            
            setappdata(app.UIFigure,'w2',w2)
            setappdata(app.UIFigure,'pw2',pw2)
            setappdata(app.UIFigure,'ps2',ps2)
            setappdata(app.UIFigure,'ci1',ci1)
        end

        % Button pushed function: RestartButton
        function Restart(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            app.ValuesLabel.Text = sprintf('Values');
            app.Label.Text = sprintf('');
            legend(app.UIAxes3,'off')
            legend(app.UIAxes4,'off')
            app.FractalExponentEditField.Value = 0;
            set(app.FractalExponentEditField, 'Enable', 'off'); set(app.FractalExponentLabel, 'Enable', 'off')
            set(app.FlightaltitudekmEditField, 'Enable', 'off'); set(app.FlightaltitudekmEditFieldLabel, 'Enable', 'off')
            set(app.SelectpointsButton,'Enable','off')
            set(app.RestartButton,'Enable','off')
            set(app.SurfacetemperatureCEditField, 'Enable', 'off'); set(app.SurfacetemperatureCEditFieldLabel, 'Enable', 'off')
            set(app.CurietemperatureCEditField,'Enable','off'); set(app.CurietemperatureCEditFieldLabel,'Enable','off')
            set(app.SurfaceheatproductionmicroWm3EditField,'Enable','off'); set(app.SurfaceheatproductionmicroWm3EditFieldLabel,'Enable','off')
            set(app.EmpiricalheatproductiondepthdistributionparameterkmEditField,'Enable','off');set(app.EmpiricalheatproductiondepthdistributionparameterkmLabel,'Enable','off')
            set(app.ThermalconductivityWmKEditField,'Enable','off'); set(app.ThermalconductivityWmKEditFieldLabel,'Enable','off')
            set(app.PlotGeothermButton,'Enable','off')
            set(app.ResolutionkmEditField, 'Enable', 'off');
            set(app.ResolutionkmEditFieldLabel, 'Enable', 'off');
        end

        % Button pushed function: SelectpointsButton
        function Selectpoints(app, event)
            a2 = getappdata(app.UIFigure,'data');
            b2 = app.FractalExponentEditField.Value;
            w2 = getappdata(app.UIFigure,'w2');
            pw2 = getappdata(app.UIFigure,'pw2');
            ps2 = getappdata(app.UIFigure,'ps2');
            ci1 = getappdata(app.UIFigure,'ci1');
            Zc = app.FlightaltitudekmEditField.Value;
            
            % Figura 1
            f1 = figure;
            plot(w2,pw2,'k.-')
            ylabel('$\ln(A(k))$','Interpreter','latex','FontSize',12)
            xlabel('$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            xlim([0 w2(end)]);
            ylim([floor(min(pw2)) ceil(max(pw2)+1)]);
            [~,xs2,ys2] = selectdata('sel','br','BrushShape','rect','BrushSize',0.01);
            close(f1)
            p2=polyfit(xs2,ys2,1); %slope Zt and intercept
            wh2=min(xs2)-0.001:0.001:max(xs2)+0.001; %wavenumber to graphs
            kk2=polyval(p2,wh2); %values for files
            Zt=(-1)*(p2(1)/(2*pi));
            sd2=sqrt(sum((ys2-polyval(p2,xs2)).^2)/(length(xs2))); %standart deviation
            Zt_error= sd2/((2*pi)*(max(xs2)-min(xs2)));
            
            % Figura 2
            f2=figure;
            plot(w2,ps2,'k.-');
            ylabel('$\ln(\frac{A(k)}{2\pi k}$)','Interpreter','latex','FontSize',12)
            xlabel('$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            xlim([0 w2(end)]);
            ylim([floor(min(ps2)) ceil(max(ps2)+1)]);
            [~,xs,ys] = selectdata('sel','br','BrushShape','rect','BrushSize',0.01);
            close(f2)
            pl = polyfit(xs,ys,1); %slope Z0 and intercept
            w1 = min(xs)-0.002:0.001:max(xs)+0.002; %wavenumber to graphs
            kk = polyval(pl,w1); %values for files
            Z0 = (-1)*(pl(1)/(2*pi));
            sd1 = sqrt(sum((ys-polyval(pl,xs)).^2)/(length(xs))); %standart deviation
            p1_err = sd1/((2*pi)*(max(xs)-min(xs)));
            Zb = ((2*Z0)-Zt); %depth to the bottom of the magnetic source (DBMS)
            Zb_err = sqrt((4*(p1_err^2))+(Zt_error^2)); %DBMS uncertainity (Martos et al., 2019)
            
            %% Forward model
            c1 = find(pw2==ys2(end));
            sx=w2(1:c1);
            sy=pw2(1:c1); %Amplitude spectrum
            d = exp(sy);
            G = exp(-2*pi*sx*Zt).*(1-exp(-2*pi*sx*(Zb-Zt)));
            x0 = log((G'*G)\G'*d);
            func = @(x0,x)x0 - ((2*pi*x)*Zt) + log((1-exp(-2*pi*x*(Zb-Zt))));
            [xc,resnorm] = lsqcurvefit(func,x0,sx,sy);
            mf=sqrt(resnorm/length(sx));
            wn=linspace(w2(1),w2(end));
            fm1=func(xc,wn); %Synthetic Spectrum
            fit_fm=mf; %Misfit
            
            % Plot 1
            plot(app.UIAxes,w2,pw2,'k.-');
            ylabel(app.UIAxes,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,wh2,kk2,'-r','Linewidth',1,'Markersize',12)
            hold(app.UIAxes,'off')
            app.UIAxes.XLim = [0 0.2];
            
            % Plot 2
            plot(app.UIAxes2,w2,ps2,'k.-');
            ylabel(app.UIAxes2,'$\ln(\frac{A(k)}{2\pi k}$)','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes2,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,w1,kk,'-r','Linewidth',1,'Markersize',12)
            hold(app.UIAxes2,'off')
            app.UIAxes2.XLim = [0 0.2];
            app.UIAxes2.YLim = [floor(min(ps2(w2<0.2))) ceil(max(ps2) + 1)];
            
            % Plot3 3
                % Confidence intervals
            p4 = plot(app.UIAxes3,w2,pw2 + ci1,'k');
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,w2,pw2 - ci1,'k')
            plot(app.UIAxes3,w2,a2(:,2) + ci1,'k')
            plot(app.UIAxes3,w2,a2(:,2) - ci1,'k')
                
                %Plots
            p2 = plot(app.UIAxes3,w2,pw2,'b-','Linewidth',1,'Markersize',12);
            p1 = plot(app.UIAxes3,w2,a2(:,2),'g-','Linewidth',1,'Markersize',12);
            p3 = plot(app.UIAxes3,wn,fm1,'-r','Linewidth',1.5,'Markersize',12);
            ylabel(app.UIAxes3,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes3,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            hold(app.UIAxes3,'off')
            app.UIAxes3.XLim = [0 0.2];
            legend(app.UIAxes3,[p1 p2 p3 p4],{'Observed spectrum','Corrected spectrum',...
                'Synthetic spectrum','95% confidence interval'})
            
            % Values
            app.ValuesLabel.Text = sprintf('Values\nFractal exponent α: %0.2f\nZt = %0.2f km \x00B1 %0.2f km\nZ0 = %0.2f km \x00B1 %0.2f km\nZb = %0.2f km \x00B1 %0.2f km\nMisfit = %0.4f',...
                b2,Zt-Zc,Zt_error,Z0-Zc,p1_err,Zb-Zc,Zb_err,fit_fm);
            
            % Enables buttons    
            set(app.SurfacetemperatureCEditField, 'Enable', 'on'); set(app.SurfacetemperatureCEditFieldLabel, 'Enable', 'on')
            set(app.CurietemperatureCEditField,'Enable','on'); set(app.CurietemperatureCEditFieldLabel,'Enable','on')
            set(app.SurfaceheatproductionmicroWm3EditField,'Enable','on'); set(app.SurfaceheatproductionmicroWm3EditFieldLabel,'Enable','on')
            set(app.EmpiricalheatproductiondepthdistributionparameterkmEditField,'Enable','on');set(app.EmpiricalheatproductiondepthdistributionparameterkmLabel,'Enable','on')
            set(app.ThermalconductivityWmKEditField,'Enable','on'); set(app.ThermalconductivityWmKEditFieldLabel,'Enable','on')
            set(app.PlotGeothermButton,'Enable','on')
            
            setappdata(app.UIFigure,'b2',b2)
            setappdata(app.UIFigure,'Zt',Zt)
            setappdata(app.UIFigure,'Zt_error',Zt_error)
            setappdata(app.UIFigure,'Z0',Z0)
            setappdata(app.UIFigure,'p1_err',p1_err)
            setappdata(app.UIFigure,'Zb',Zb)
            setappdata(app.UIFigure,'Zb_err',Zb_err)
            setappdata(app.UIFigure,'fit_fm',fit_fm)
            setappdata(app.UIFigure,'xs',xs)
            setappdata(app.UIFigure,'xs2',xs2)
            setappdata(app.UIFigure,'Zc',Zc)
        end

        % Value changed function: ResolutionkmEditField
        function Resolutionandraas(app, event)
            dl = app.ResolutionkmEditField.Value;
            b = getappdata(app.UIFigure,'b');
            dt = detrend_2d(app,b); % Polynomial Trend Surface Estimation (degree = 1)
            a2 = raas(app,dt,dl); % Radially average power spectrum
            plot(app.UIAxes,a2(:,1),a2(:,2),'k.-');
            ylabel(app.UIAxes,'$\ln(A(k))$','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            app.UIAxes.XLim = [0 a2(end,1)];
            app.UIAxes.YLimMode = 'auto';
                
            plot(app.UIAxes2,a2(:,1),a2(:,2)-log(2*pi*a2(:,1)),'k.-');
            ylabel(app.UIAxes2,'$\ln(\frac{A(k)}{2\pi k}$)','Interpreter','latex','FontSize',12)
            xlabel(app.UIAxes2,'$k\;(\frac{1}{km})$','Interpreter','latex','FontSize',12)
            app.UIAxes2.XLim = [0 a2(end,1)];
            app.UIAxes2.YLimMode = 'auto';
            
            setappdata(app.UIFigure,'data',a2)
            xs = []; % Data not selected yet
            xs2 = []; % Data not selected yet
            setappdata(app.UIFigure,'xs',xs)
            setappdata(app.UIFigure,'xs2',xs2)
            % Initial value
            [w2,pw2,ps2,ci1] = fractal_correction(app,a2,0);
            
            set(app.FractalExponentEditField, 'Enable', 'on'); set(app.FractalExponentLabel, 'Enable', 'on')
            set(app.FlightaltitudekmEditField, 'Enable', 'on'); set(app.FlightaltitudekmEditFieldLabel, 'Enable', 'on')
            set(app.SelectpointsButton,'Enable','on')
            set(app.RestartButton,'Enable','on')
            
            app.FractalExponentEditField.Value = 0;
            setappdata(app.UIFigure,'w2',w2)
            setappdata(app.UIFigure,'pw2',pw2)
            setappdata(app.UIFigure,'ps2',ps2)
            setappdata(app.UIFigure,'ci1',ci1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'k (1/km)')
            ylabel(app.UIAxes, 'ln(A(k))')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'k (1/km)')
            ylabel(app.UIAxes2, 'ln(A(k)/k)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'k (1/km)')
            ylabel(app.UIAxes3, 'ln(A(k))')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Steady-state one-dimensional geotherm')
            xlabel(app.UIAxes4, 'Temperature (°C)')
            ylabel(app.UIAxes4, 'Depth (km)')
            app.FractalExponentLabel = uilabel(app.UIFigure);
            app.FractalExponentLabel.Enable = 'off';
            app.FractalExponentLabel.Text = 'Fractal Exponent (α)';
            app.FractalExponentEditField = uieditfield(app.UIFigure, 'numeric');
            app.FractalExponentEditField.Limits = [0 Inf];
            app.FractalExponentEditField.ValueChangedFcn = createCallbackFcn(app, @Fractal, true);
            app.FractalExponentEditField.Enable = 'off';
            app.SelectpointsButton = uibutton(app.UIFigure, 'push');
            app.SelectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @Selectpoints, true);
            app.SelectpointsButton.Enable = 'off';
            app.SelectpointsButton.Text = 'Select points';
            app.ValuesLabel = uilabel(app.UIFigure);
            app.ValuesLabel.Text = 'Values';
            app.SurfacetemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.SurfacetemperatureCEditFieldLabel.Enable = 'off';
            app.SurfacetemperatureCEditFieldLabel.Text = 'Surface temperature (°C)';
            app.SurfacetemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurfacetemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @Geotherm, true);
            app.SurfacetemperatureCEditField.Enable = 'off';
            app.SurfacetemperatureCEditField.Value = 20;
            app.CurietemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.CurietemperatureCEditFieldLabel.Enable = 'off';
            app.CurietemperatureCEditFieldLabel.Text = 'Curie temperature (°C)';
            app.CurietemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurietemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @Geotherm, true);
            app.CurietemperatureCEditField.Enable = 'off';
            app.CurietemperatureCEditField.Value = 580;
            app.SurfaceheatproductionmicroWm3EditFieldLabel = uilabel(app.UIFigure);
            app.SurfaceheatproductionmicroWm3EditFieldLabel.Enable = 'off';
            app.SurfaceheatproductionmicroWm3EditFieldLabel.Text = 'Surface heat production (microW/m3)';
            app.SurfaceheatproductionmicroWm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.SurfaceheatproductionmicroWm3EditField.ValueChangedFcn = createCallbackFcn(app, @Geotherm, true);
            app.SurfaceheatproductionmicroWm3EditField.Enable = 'off';
            app.SurfaceheatproductionmicroWm3EditField.Value = 2.5;
            app.EmpiricalheatproductiondepthdistributionparameterkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EmpiricalheatproductiondepthdistributionparameterkmEditField.ValueChangedFcn = createCallbackFcn(app, @Geotherm, true);
            app.EmpiricalheatproductiondepthdistributionparameterkmEditField.Enable = 'off';
            app.EmpiricalheatproductiondepthdistributionparameterkmEditField.Value = 10;
            app.ThermalconductivityWmKEditFieldLabel = uilabel(app.UIFigure);
            app.ThermalconductivityWmKEditFieldLabel.Enable = 'off';
            app.ThermalconductivityWmKEditFieldLabel.Text = 'Thermal conductivity (W/mK)';
            app.ThermalconductivityWmKEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThermalconductivityWmKEditField.ValueChangedFcn = createCallbackFcn(app, @Geotherm, true);
            app.ThermalconductivityWmKEditField.Enable = 'off';
            app.ThermalconductivityWmKEditField.Value = 2.2;
            app.PlotGeothermButton = uibutton(app.UIFigure, 'push');
            app.PlotGeothermButton.ButtonPushedFcn = createCallbackFcn(app, @Geotherm, true);
            app.PlotGeothermButton.Enable = 'off';
            app.PlotGeothermButton.Text = 'Plot Geotherm';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @Restart, true);
            app.RestartButton.Enable = 'off';
            app.RestartButton.Text = 'Restart';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @Readdata, true);
            app.SelectFileButton.Text = 'Select File';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FlightaltitudekmEditFieldLabel = uilabel(app.UIFigure);
            app.FlightaltitudekmEditFieldLabel.Enable = 'off';
            app.FlightaltitudekmEditFieldLabel.Text = 'Flight altitude (km)';
            app.FlightaltitudekmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlightaltitudekmEditField.Enable = 'off';
            app.EmpiricalheatproductiondepthdistributionparameterkmLabel = uilabel(app.UIFigure);
            app.EmpiricalheatproductiondepthdistributionparameterkmLabel.Enable = 'off';
            app.EmpiricalheatproductiondepthdistributionparameterkmLabel.Text = 'Empirical heat-productiondepth-distribution parameter (km) ';
            app.DefractalMethodLabel = uilabel(app.UIFigure);
            app.DefractalMethodLabel.Text = 'Defractal Method';
            app.ResolutionkmEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionkmEditFieldLabel.Enable = 'off';
            app.ResolutionkmEditFieldLabel.Text = 'Resolution (km)';
            app.ResolutionkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionkmEditField.Limits = [0 Inf];
            app.ResolutionkmEditField.ValueChangedFcn = createCallbackFcn(app, @Resolutionandraas, true);
            app.ResolutionkmEditField.Enable = 'off';
            app.ResolutionkmEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defractal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end